classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        HorizontalLabel           matlab.ui.control.Label
        HorizontalSlider          matlab.ui.control.Slider
        VerticalLabel             matlab.ui.control.Label
        VerticalSlider            matlab.ui.control.Slider
        HorizontalangleKnobLabel  matlab.ui.control.Label
        HorizontalangleKnob       matlab.ui.control.Knob
        VerticalangleKnobLabel    matlab.ui.control.Label
        VerticalangleKnob         matlab.ui.control.Knob
        CubeDimensionKnobLabel    matlab.ui.control.Label
        CubeDimensionKnob         matlab.ui.control.Knob
    end

    
    properties (Access = private)
        % Rubik cube
        rubikDim = 3;
        cubeW = 100;
        colors = [["b", "g"]; ["m", "r"]; ["k", "y"]];
        
        % Camera
        aspectRatio = 1.7777777777;
        angle = 1.0471976 * 0.5; % camFOV * 0.5
        camFar = 4462.1959;
        camNear = 44.621959;
        
        ampli = 0;
        
        angH0 = 0.25 * pi;
        angV0 = 0.25 * pi;
        deltaH = 0;
        deltaV = 0;
        
        angH = 0;
        angV = 0;
        
        
        % camera results:
        camRotationMatrix = 0;
        camCoord = 0;
        planeV = 0;
        planeH = 0;
        planeVectors = 0;
        
        % Screen control
        hSelection = 0;
        vSelection = 0;
        mouseDot = 0;
    end
    
    methods (Access = private)
        
        function [matrix] = rotationMatrix3D(~, a, angle)
        % Returns the corresponding 3D rotation matrix
            if a == "X"
                matrix = [ ...
                    [1, 0, 0]; ...
                    [0, cos(angle), -sin(angle)]; ...
                    [0, sin(angle), cos(angle)] ...
                ];
            elseif a == "Y"
                matrix = [ ...
                    [cos(-angle), 0, sin(-angle)]; ...
                    [0, 1, 0]; ...
                    [-sin(-angle), 0, cos(-angle)] ...
                ];
            else
                matrix = [ ...
                    [cos(angle), -sin(angle), 0]; ...
                    [sin(angle), cos(angle), 0]; ...
                    [0, 0, 1] ...
                ];
            end
        end
        
        function [] = updateVariables(app)
            % Update ampli and update the axis based on the value
            app.ampli = 2^(0.25 * app.rubikDim) * 300;
            mult = 1.1;
            axis(app.UIAxes, [ ...
                -app.ampli * mult, app.ampli * mult, ...
                -app.ampli * mult, app.ampli * mult, ...
                -app.ampli * mult, app.ampli * mult  ...
            ])
            
            % Get angle of the camera
            app.angH = app.angH0 + app.deltaH;
            app.angV = app.angV0 + app.deltaV;
            
            app.camRotationMatrix = rotationMatrix3D(app, "Z", app.angH) * rotationMatrix3D(app, "Y", app.angV);
            app.camCoord = app.camRotationMatrix * [app.ampli, 0, 0]';
            
            % Visual plane
            app.planeV = app.ampli * tan(app.angle);
            app.planeH = app.planeV * app.aspectRatio;
            
            app.planeVectors = [ ...
                app.camRotationMatrix * [0;  app.planeH;  app.planeV], ...
                app.camRotationMatrix * [0;  app.planeH; -app.planeV], ...
                app.camRotationMatrix * [0; -app.planeH; -app.planeV], ...
                app.camRotationMatrix * [0; -app.planeH;  app.planeV] ...
            ];
            
            mouseV = [0; app.hSelection * app.planeH; app.vSelection * app.planeV];
            app.mouseDot = app.camRotationMatrix * mouseV;
            
            updatePlot(app);
        end
        
        function [] = updatePlot(app)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            
            % Draw visual help
            ejesX = [[1, -1]; [1, -1]; [0, 0]];
            ejesY = [[1, -1]; [-1, 1]; [0, 0]];
            ejesZ = [[0,  0]; [0,  0]; [sqrt(2), -sqrt(2)]];
            for x = 1:3
                plot3(app.UIAxes, ejesX(x, :) .* app.cubeW, ejesY(x, :) .* app.cubeW, ejesZ(x, :) .* app.cubeW, "k-");
            end
            
            
            % draw Cube border
            pieces = [-app.rubikDim / 2, app.rubikDim / 2] .* app.cubeW;
            for p1 = pieces
                for p2 = pieces
                    plot3(app.UIAxes, [p1, p1], [p2, p2], pieces, "k")
                    plot3(app.UIAxes, [p1, p1], pieces, [p2, p2], "k")
                    plot3(app.UIAxes, pieces, [p1, p1], [p2, p2], "k")
                end
            end
            
            % draw interior lines:
            if mod(app.rubikDim, 2) == 0
                m = 0 : app.rubikDim / 2 - 1;
            else
                m = 0.5 : app.rubikDim / 2 - 0.5;
            end
            
            i = 1;
            for p1 = pieces
                for mm = m
                    pieces2 = [-1, 1] .* (mm * app.cubeW);
                    for p2 = pieces2
                        % Blue and green
                        plot3(app.UIAxes, [p1, p1], [p2, p2], pieces, app.colors(1, i + 1))
                        plot3(app.UIAxes, [p1, p1], pieces, [p2, p2], app.colors(1, i + 1))
                        
                        % Orange and red
                        plot3(app.UIAxes, [p2, p2], [p1, p1], pieces, app.colors(2, i + 1))
                        plot3(app.UIAxes, pieces, [p1, p1], [p2, p2], app.colors(2, i + 1))
                        
                        % White and yellow
                        plot3(app.UIAxes, [p2, p2], pieces, [p1, p1], app.colors(3, i + 1))
                        plot3(app.UIAxes, pieces, [p2, p2], [p1, p1], app.colors(3, i + 1))
                    end
                end
                i = mod(i + 1, 2);
            end
            
            
            % Camera
            plot3(app.UIAxes, [app.camCoord(1), 0], [app.camCoord(2), 0], [app.camCoord(3), 0], "b-*");
            
            % Visual plane
            for i = 1:4
                p1 = app.planeVectors(:, i)';
                index = i + 1;
                if index == 5
                    index = 1;
                end
                p2 = app.planeVectors(:, index)';
                % Print visual plane
                plot3(app.UIAxes, [p1(1), p2(1)], [p1(2), p2(2)], [p1(3), p2(3)], "g-*");
                % Connection camera - visual plane
                plot3(app.UIAxes, [p1(1), app.camCoord(1)], [p1(2), app.camCoord(2)], [p1(3), app.camCoord(3)], "k-");
            end
            
            % Mouse dot
            plot3(app.UIAxes, app.mouseDot(1), app.mouseDot(2), app.mouseDot(3), "m*")
            
            
            % Detection of mouse on cube
            mouseN = app.camCoord / norm(app.camCoord) * -1;
            
            inBounds = 0.5 * app.rubikDim * app.cubeW;
            
                        
            iterator = mouseN * inBounds * sqrt(3) : inBounds / 20 : - mouseN * inBounds * sqrt(2);
            for iV = iterator
                v = app.mouseDot - iV;
                if all(abs(v) <= inBounds)
                    plot3(app.UIAxes, v(1), v(2), v(3), "r*")
                    break
                end
            end
            
            if mod(app.rubikDim, 2) == 0
                indexConv = @(coor) ceil(coor / app.cubeW + app.rubikDim / 2);
            else
                indexConv = @(coor) ceil(coor / app.cubeW + app.rubikDim / 2 - 0.5);
            end
            
            index = indexConv(v);
            [~, ind] = max(abs(v));
            vectors = eye(3);
            vectors(:, ind) = []; % Now there're 2 vectors with the directions possible
            v
            vectors
            index
            
            
            hold(app.UIAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup plot3 properties
            rotate3d(app.UIAxes,'on');
            xlabel(app.UIAxes, "X"); ylabel(app.UIAxes, "Y"); zlabel(app.UIAxes, "Z");
            view(app.UIAxes, 135, 45)
            grid(app.UIAxes, 'MINOR');
            
            %Setup variables and update the plot
            updateVariables(app);
        end

        % Callback function: HorizontalSlider, VerticalSlider
        function HorizontalSliderValueChanging(app, event)
            app.hSelection = event.Value / 50 - 1;
            updateVariables(app);
        end

        % Value changing function: VerticalSlider
        function VerticalSliderValueChanging(app, event)
            app.vSelection = event.Value / 50 - 1;
            updateVariables(app);
        end

        % Value changed function: CubeDimensionKnob
        function CubeDimensionKnobValueChanged(app, event)
            app.rubikDim = app.CubeDimensionKnob.Value;
            updateVariables(app);
        end

        % Value changing function: HorizontalangleKnob
        function HorizontalangleKnobValueChanging(app, event)
            app.deltaH = app.HorizontalangleKnob.Value;
            updateVariables(app);
        end

        % Value changing function: VerticalangleKnob
        function VerticalangleKnobValueChanging(app, event)
            app.deltaV = app.VerticalangleKnob.Value;
            updateVariables(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.HorizontalLabel = uilabel(app.UIFigure);
            app.HorizontalLabel.Text = 'Horizontal (%)';
            app.HorizontalSlider = uislider(app.UIFigure);
            app.HorizontalSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.HorizontalSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.HorizontalSlider.ValueChangingFcn = createCallbackFcn(app, @HorizontalSliderValueChanging, true);
            app.HorizontalSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.HorizontalSlider.Value = 50;
            app.VerticalLabel = uilabel(app.UIFigure);
            app.VerticalLabel.Text = 'Vertical (%)';
            app.VerticalSlider = uislider(app.UIFigure);
            app.VerticalSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.VerticalSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.VerticalSlider.ValueChangedFcn = createCallbackFcn(app, @HorizontalSliderValueChanging, true);
            app.VerticalSlider.ValueChangingFcn = createCallbackFcn(app, @VerticalSliderValueChanging, true);
            app.VerticalSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.VerticalSlider.Value = 50;
            app.HorizontalangleKnobLabel = uilabel(app.UIFigure);
            app.HorizontalangleKnobLabel.Text = 'Horizontal angle';
            app.HorizontalangleKnob = uiknob(app.UIFigure, 'continuous');
            app.HorizontalangleKnob.Limits = [-6.28318530717959 6.28318530717959];
            app.HorizontalangleKnob.MajorTicks = [-6.28318530717959 -4.71238898038469 -3.14159265358979 -1.5707963267949 0 1.5707963267949 3.14159265358979 4.71238898038469 6.28318530717959];
            app.HorizontalangleKnob.MajorTickLabels = {'-2Π', '-3Π/2', '-Π', '-Π/2', '0', 'Π/2', 'Π', '3Π/2', '2Π'};
            app.HorizontalangleKnob.ValueChangingFcn = createCallbackFcn(app, @HorizontalangleKnobValueChanging, true);
            app.VerticalangleKnobLabel = uilabel(app.UIFigure);
            app.VerticalangleKnobLabel.Text = 'Vertical angle';
            app.VerticalangleKnob = uiknob(app.UIFigure, 'continuous');
            app.VerticalangleKnob.Limits = [-6.28318530717959 6.28318530717959];
            app.VerticalangleKnob.MajorTicks = [-6.28318530717959 -4.71238898038469 -3.14159265358979 -1.5707963267949 0 1.5707963267949 3.14159265358979 4.71238898038469 6.28318530717959];
            app.VerticalangleKnob.MajorTickLabels = {'-2Π', '-3Π/2', '-Π', '-Π/2', '0', 'Π/2', 'Π', '3Π/2', '2Π'};
            app.VerticalangleKnob.ValueChangingFcn = createCallbackFcn(app, @VerticalangleKnobValueChanging, true);
            app.CubeDimensionKnobLabel = uilabel(app.UIFigure);
            app.CubeDimensionKnobLabel.Text = 'Cube Dimension';
            app.CubeDimensionKnob = uiknob(app.UIFigure, 'continuous');
            app.CubeDimensionKnob.Limits = [2 8];
            app.CubeDimensionKnob.MajorTicks = [2 3 4 5 6 7 8];
            app.CubeDimensionKnob.ValueChangedFcn = createCallbackFcn(app, @CubeDimensionKnobValueChanged, true);
            app.CubeDimensionKnob.MinorTicks = 2;
            app.CubeDimensionKnob.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end